classdef UappQProgramming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel_6                     matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        Title                       matlab.ui.control.Label
        UniversityLogo              matlab.ui.control.Image
        TabGroup                    matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        Panel_5                     matlab.ui.container.Panel
        WattsLabel                  matlab.ui.control.Label
        PowerEditFieldLabel         matlab.ui.control.Label
        Power                       matlab.ui.control.NumericEditField
        SmoothLissajousSwitch       matlab.ui.control.Switch
        SmoothLissajousFigureSwitchLabel  matlab.ui.control.Label
        LissajousPlotButton         matlab.ui.control.Button
        LissajousUIAxes             matlab.ui.control.UIAxes
        Panel_2                     matlab.ui.container.Panel
        VLabel_5                    matlab.ui.control.Label
        VLabel_6                    matlab.ui.control.Label
        Min_Q                       matlab.ui.control.NumericEditField
        MaxLabel_4                  matlab.ui.control.Label
        Max_Q                       matlab.ui.control.NumericEditField
        MaxLabel_3                  matlab.ui.control.Label
        HzLabel_2                   matlab.ui.control.Label
        CLabel_2                    matlab.ui.control.Label
        CLabel                      matlab.ui.control.Label
        Freq_q                      matlab.ui.control.NumericEditField
        FrequencyEditField_2Label   matlab.ui.control.Label
        RMSEditField_2Label         matlab.ui.control.Label
        RMS_q                       matlab.ui.control.NumericEditField
        Peaktopeak_q                matlab.ui.control.NumericEditField
        PeaktopeakEditField_2Label  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        VLabel_2                    matlab.ui.control.Label
        Max_Uapp                    matlab.ui.control.NumericEditField
        Min_Uapp                    matlab.ui.control.NumericEditField
        MaxLabel_2                  matlab.ui.control.Label
        MaxLabel                    matlab.ui.control.Label
        VLabel_4                    matlab.ui.control.Label
        VLabel_3                    matlab.ui.control.Label
        VLabel                      matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        RMS_uapp                    matlab.ui.control.NumericEditField
        RMSEditFieldLabel           matlab.ui.control.Label
        Freq_uapp                   matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel     matlab.ui.control.Label
        Peaktopeak_uapp             matlab.ui.control.NumericEditField
        PeaktopeakEditFieldLabel    matlab.ui.control.Label
        Panel_4                     matlab.ui.container.Panel
        ImportButton_Uc             matlab.ui.control.Button
        FilenameUc                  matlab.ui.control.EditField
        FilenameEditFieldLabel_2    matlab.ui.control.Label
        SmoothSwitch_2              matlab.ui.control.ToggleSwitch
        SmoothSwitch_2Label         matlab.ui.control.Label
        QUIAxes                     matlab.ui.control.UIAxes
        Panel_3                     matlab.ui.container.Panel
        FilenameUapp                matlab.ui.control.EditField
        FilenameEditFieldLabel      matlab.ui.control.Label
        ImportButton_Uapp           matlab.ui.control.Button
        SmoothSwitch                matlab.ui.control.ToggleSwitch
        SmoothSwitchLabel           matlab.ui.control.Label
        UappUIAxes                  matlab.ui.control.UIAxes
        DetailsTab                  matlab.ui.container.Tab
        Panel_9                     matlab.ui.container.Panel
        DropDown_3                  matlab.ui.control.DropDown
        PowerEditFieldLabel_2       matlab.ui.control.Label
        Power_2                     matlab.ui.control.NumericEditField
        WattsLabel_2                matlab.ui.control.Label
        PlotPeaksButton             matlab.ui.control.Button
        UappQPeaksAxes              matlab.ui.control.UIAxes
        Panel_8                     matlab.ui.container.Panel
        DropDown                    matlab.ui.control.DropDown
        Freq_uapp_2                 matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_2   matlab.ui.control.Label
        HzLabel_3                   matlab.ui.control.Label
        VLabel_7                    matlab.ui.control.Label
        Peaktopeak_uapp_2           matlab.ui.control.NumericEditField
        Peaktopeak_uapp_label       matlab.ui.control.Label
        PlotPeaksButton_2           matlab.ui.control.Button
        UappPeaksAxes               matlab.ui.control.UIAxes
        Panel_7                     matlab.ui.container.Panel
        DropDown_2                  matlab.ui.control.DropDown
        HzLabel_4                   matlab.ui.control.Label
        Freq_q_2                    matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_3   matlab.ui.control.Label
        VLabel_8                    matlab.ui.control.Label
        Peaktopeak_q_label          matlab.ui.control.Label
        Peaktopeak_q_2              matlab.ui.control.NumericEditField
        PlotPeaksButton_3           matlab.ui.control.Button
        QPeaksAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        uapp_x
        uapp_y
        q_x
        q_y
        t_config %time after config
        uapp_yfilter % uapp after filter
        q_yfilter % q after filter
        locs_Maxvolt %peak position of uapp
        locs_Maxcharge %peak position of q
        locs_Minvolt
        locs_Mincharge
    end


    methods (Access = public)

        % Create find_details function with return values which are lowest
        % and highest points, P2P, RMS, Peak Position, InvPeakPosition,
        % frequency
        function [min_value,max_value,p2p_value,rms_value,peak,invpeak,f_value]=find_details(~,xvalue,yvalue)
            %% Find the lowest and highest points %%

            % a is the lowest point
            % b is the highest point

            min_value=min(yvalue);
            max_value=max(yvalue);

            %% Find RMS %%

            % Use RMS matlab function
            rms_value=rms(yvalue);

            %% Find Every Peaks (Calculate Frequency & P2P)

            % Use findpeaks to find the peaks (refers to maximum points) of signal
            % Set the distance equals 100 means that the next peak should be away from current peak more than 100 samples
            % the samples after filtered is 3900 samples, so if the input signal change, this number should clarify again
            % for complex signals, more parameters should add as the arguments of findpeaks function


            % peak refers to the peak of the graph while invpeak refers to pposite of the peak of the graph
            % p2p can find from the highest minus the lowest point of each cycle

            % Set the reference point equals RMS of their signal to make sure that Peak point is not a spike from noise

            [~,peak] = findpeaks(yvalue,'MinPeakHeight',rms(yvalue),'MinPeakDistance',100);
            [~,invpeak] = findpeaks(-1.*yvalue,'MinPeakHeight',rms(yvalue),'MinPeakDistance',100);

            %find the number of peaks
            n=size(peak,1);
            f=0;
            p2p_u=0;

            % Use average frequency from every cycles
            for i=1:n-1
                f=f+1/(xvalue(peak(i+1))-xvalue(peak(i)));
                p2p_u=p2p_u+yvalue(peak(i))-yvalue(invpeak(i));
            end

            % Use average p2p and freq of every peaks
            f_value=f/(n-1);
            p2p_value= p2p_u/(n-1);

            % Use only first cycle to calculate frequency
            % f1=1/(app.t_config(locs_Maxvolt(2))-app.t_config(locs_Maxvolt(1)));

        end

        function [yfilter,xfilter] = filter_data(~,xvalue,yvalue)
            %% Down Sampling %%
            % Set sampling size = 50
            % so the samples from 200000 will be 4000 samples
            % Average 50 samples and keep value in a sample
            down_size=50;
            m=size(yvalue,1);
            signal_ydown=zeros(uint64(m/down_size),1);
            for i=1:uint64(m/down_size)
                for j=1:down_size
                    signal_ydown(i)= signal_ydown(i)+yvalue(down_size*(i-1)+j);
                end
                signal_ydown(i)= signal_ydown(i)/down_size;
            end

            %% Moving Average %%
            % Set the moving average size =100
            % so the samples from 4000 with be 3900 samples
            % this moving average function cut the last 100 samples
            % if the signal is unknown, I would suggest to use zero-padding
            % or nearest border as values for padding

            m=size(signal_ydown,1);
            moving_size=100; %round(weight);
            signal_ymov=zeros(m-moving_size,1);
            for i=1:m-moving_size
                for j=1:moving_size
                    signal_ymov(i)= signal_ymov(i)+signal_ydown(i+j);
                end
                signal_ymov(i)= signal_ymov(i)/moving_size;
            end


            %% X-axis /Time config %%
            % Configurate time by the description of the filters that
            % applied to signals
            yfilter =signal_ymov;
            signal_xdown=xvalue(1:down_size:size(xvalue,1));
            signal_xmov=signal_xdown(1:size(signal_xdown,1)-moving_size);
            xfilter=signal_xmov(1:size(signal_xmov,1));

        end


        %create power calculation function with return value
        function power_value= power_calculation(app)
            %% Power Calculation
            n=size(app.locs_Maxcharge,1);
            power=0;

            % Calculate the power of each cycle
            % We detect n peak so we get n-1 cycle
            for i=1:n-1
                locstart=app.locs_Maxcharge(i);
                locstop=app.locs_Maxcharge(i+1);

                m=locstop-locstart+1;
                line=zeros(m,2);
                k=1;
                for j=locstart:locstop
                    % Create the "line" array (size=k*2) representing value
                    % of Uapp and Q in each cycle
                    line(k,1)=app.uapp_yfilter(j);
                    line(k,2)=app.q_yfilter(j);
                    k=k+1;
                end
                % Power of each cycle
                %The computation of “polyarea” is based on the Gauss polygon
                % area formula and the polygon automatically be closed.
                % Because of this reason, when calculate periodic signals,
                % it is necessary to calculate separate cycles.
                power = power + polyarea(line(:,1),line(:,2))*35000;

            end

            % Average power from every cycles
            n=size(app.locs_Maxcharge,1);

            power_value=power/(n-1);
        end

    end

    methods (Access = private)

        function [p2p_u,f] = find_p2pfq(~,xvalue,yvalue,order)
            [~,peak] = findpeaks(yvalue,'MinPeakHeight',rms(yvalue),'MinPeakDistance',100);
            [~,invpeak] = findpeaks(-1.*yvalue,'MinPeakHeight',rms(yvalue),'MinPeakDistance',100);

            f=0;
            p2p_u=0;

            % Use average frequency from every cycles
            i=order;
            f=f+1/(xvalue(peak(i+1))-xvalue(peak(i)));
            p2p_u=p2p_u+yvalue(peak(i))-yvalue(invpeak(i));

        end

        function power = power_cycle(app,order)
            %% Power Calculation
            power=0;

            % Calculate the power of each cycle
            % We detect n peak so we get n-1 cycle
            locstart=app.locs_Maxvolt(order);
            locstop=app.locs_Maxvolt(order+1);

            m=locstop-locstart+1;
            line=zeros(m,2);
            k=1;
            for j=locstart:locstop
                % Create the "line" array (size=k*2) representing value
                % of Uapp and Q in each cycle
                line(k,1)=app.uapp_yfilter(j);
                line(k,2)=app.q_yfilter(j);
                k=k+1;
            end
            % Power of each cycle
            %The computation of “polyarea” is based on the Gauss polygon
            % area formula and the polygon automatically be closed.
            % Because of this reason, when calculate periodic signals,
            % it is necessary to calculate separate cycles.
            power = power + polyarea(line(:,1),line(:,2))*35000;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.Peaktopeak_q.Value=0;
            app.Peaktopeak_uapp.Value=0;
            app.RMS_q.Value=0;
            app.RMS_uapp.Value=0;
            app.Freq_q.Value=0;
            app.Freq_uapp.Value=0;
            app.Min_Uapp.Value=0;
            app.Max_Uapp.Value=0;
            app.Min_Q.Value=0;
            app.Max_Q.Value=0;
            app.Power.Value=0;
            app.uapp_x(1)=0;
            app.uapp_y(1)=0;
            app.q_x(1)=0;
            app.q_y(1)=0;

            % Set Peak Display of Uapp and Q Axis
            yyaxis(app.UappQPeaksAxes,"left")
            ylabel(app.UappQPeaksAxes,'Voltage (Volts)')
            yyaxis(app.UappQPeaksAxes,"right")
            ylabel(app.UappQPeaksAxes,'Charge (Coulombs)')
            axis(app.UappQPeaksAxes,"auto")

        end

        % Button pushed function: ImportButton_Uapp
        function ImportButton_UappPushed(app, event)
            [filename,]=uigetfile('*.txt');

            % Create If-else condition for the situation that user click
            % cancel preventing an error that the app doesn't get any
            % input table from selected file

            if isequal(filename,0)
                disp('User selected Cancel');

            else
                %% importdata %%
                app.FilenameUapp.Value=filename;
                T=readtable(filename);

                app.uapp_x = table2array(T(:,1));
                app.uapp_y = table2array(T(:,2));
                plot(app.UappUIAxes,app.uapp_x,app.uapp_y,Color='#eeaf61');
                legend(app.UappUIAxes,"Uapp");
                xlim(app.UappUIAxes,[0 1e-4]);
                ylim(app.UappUIAxes,"auto");

                %%
                % Filter function %
                % input is uapp_x and uapp_y
                % output is uapp_yfilter and t_config
                [app.uapp_yfilter,app.t_config]=filter_data(app,app.uapp_x,app.uapp_y);

                % Find details function %

                % update min, max, p2p, rms, locus_max, locus_min, and freq
                % of Uapp to receive output from function find details
                [app.Min_Uapp.Value,app.Max_Uapp.Value,app.Peaktopeak_uapp.Value,app.RMS_uapp.Value,app.locs_Maxvolt,app.locs_Minvolt,app.Freq_uapp.Value]=find_details(app,app.t_config,app.uapp_yfilter);

            end

        end

        % Button pushed function: ImportButton_Uc
        function ImportButton_UcPushed(app, event)
            % The function works as same as Uapp callback function, but
            % change the object to display

            [filename, ]=uigetfile('*.txt');

            if isequal(filename,0)
                disp('User selected Cancel');

            else
                %% importdata %%
                app.FilenameUc.Value=filename;
                T=readtable(filename);
                app.q_x = table2array(T(:,1));
                app.q_y = 22*1e-9.*table2array(T(:,2));
                plot(app.QUIAxes,app.q_x,app.q_y,Color='#ee5d6c');
                legend(app.QUIAxes,"Q");
                xlim(app.QUIAxes,[0 1e-4]);
                ylim(app.QUIAxes,"auto");
                %%
                % Filter function %
                [app.q_yfilter,app.t_config]=filter_data(app,app.q_x,app.q_y);
                % Find details function %
                [app.Min_Q.Value,app.Max_Q.Value,app.Peaktopeak_q.Value,app.RMS_q.Value,app.locs_Maxcharge,app.locs_Mincharge,app.Freq_q.Value]=find_details(app,app.t_config,app.q_yfilter);

            end
        end

        % Button pushed function: LissajousPlotButton
        function LissajousPlotButtonPushed(app, event)
            plot(app.LissajousUIAxes,app.uapp_y,app.q_y,Color='#6a0d83');

            % Assign output value of power function to object Power
            % which means update power to display
            app.Power.Value=power_calculation(app);
        end

        % Value changed function: SmoothSwitch
        function SmoothSwitchValueChanged(app, event)
            %Uapp
            value = app.SmoothSwitch.Value;
            if(value=="On"&&app.uapp_y(1)~=0)
                % plot graph with smoothed data and time config
                plot(app.UappUIAxes,app.t_config,app.uapp_yfilter,Color='#eeaf61',LineWidth = 1.5);
                legend(app.UappUIAxes,'Uapp smoothed');
            elseif(app.uapp_y(1)~=0)
                % plot graph with non-smoothed data
                plot(app.UappUIAxes,app.uapp_x,app.uapp_y,Color='#eeaf61');
                legend(app.UappUIAxes,"Uapp");
            end
        end

        % Value changed function: SmoothSwitch_2
        function SmoothSwitch_2ValueChanged(app, event)
            %Q
            value = app.SmoothSwitch_2.Value;
            if(value=="On"&&app.q_y(1)~=0)
                % plot graph with smoothed data and time config
                plot(app.QUIAxes,app.t_config,app.q_yfilter,Color='#ee5d6c',LineWidth = 1.5);
                legend(app.QUIAxes,'Q smoothed');
            elseif(app.q_y(1)~=0)
                % plot graph with non-smoothed data
                plot(app.QUIAxes,app.q_x,app.q_y,Color='#ee5d6c');
                legend(app.QUIAxes,"Q");
            end
        end

        % Value changed function: SmoothLissajousSwitch
        function SmoothLissajousSwitchValueChanged(app, event)
            % Read the value from checkbox and create if-else condition for
            % smoothed/non-smoothed lissajous displays
            value = app.SmoothLissajousSwitch.Value;
            if(value=="On")
                %% Plot %%
                plot(app.LissajousUIAxes,app.uapp_yfilter(app.locs_Maxvolt(1):app.locs_Maxvolt(2)),app.q_yfilter(app.locs_Maxvolt(1):app.locs_Maxvolt(2)),Color='#6a0d83',LineWidth = 1.5);
                ylim(app.LissajousUIAxes,[-2*1e-7,2*1e-7]);
            else
                plot(app.LissajousUIAxes,app.uapp_y,app.q_y,Color='#6a0d83');
            end
        end

        % Button pushed function: PlotPeaksButton
        function PlotPeaksButtonPushed(app, event)
            [pk1,lk1] = findpeaks(app.uapp_yfilter,'MinPeakHeight',app.RMS_uapp.Value,'MinPeakDistance',100);
            [pk2,lk2] = findpeaks(app.q_yfilter,'MinPeakHeight',app.RMS_q.Value,'MinPeakDistance',100);

            yyaxis(app.UappQPeaksAxes,"left")
            plot(app.UappQPeaksAxes,app.t_config,app.uapp_yfilter,app.t_config(lk1),pk1,'o',LineWidth = 1.5)
            ylabel(app.UappQPeaksAxes,'Voltage (Volts)')

            yyaxis(app.UappQPeaksAxes,"right")
            plot(app.UappQPeaksAxes,app.t_config,app.q_yfilter,app.t_config(lk2),pk2,'o',LineWidth = 1.5)
            ylabel(app.UappQPeaksAxes,'Charge (Coulombs)')
            axis(app.UappQPeaksAxes,"auto")

        end

        % Button pushed function: PlotPeaksButton_2
        function PlotPeaksButtonUPushed(app, event)
            [pk1,lk1] = findpeaks(app.uapp_yfilter,'MinPeakHeight',app.RMS_uapp.Value,'MinPeakDistance',100);
            [pk2,lk2] = findpeaks(-1*app.uapp_yfilter,'MinPeakHeight',app.RMS_uapp.Value,'MinPeakDistance',100);

            plot(app.UappPeaksAxes,app.t_config,app.uapp_yfilter,Color='#eeaf61',LineWidth = 1.5)
            hold(app.UappPeaksAxes, 'on' )
            plot(app.UappPeaksAxes,app.t_config(lk1),pk1,'o',Color='#ce4993',LineWidth = 1.5)

            plot(app.UappPeaksAxes,app.t_config(lk2),-pk2,'o',Color='#ce4993',LineWidth = 1.5)
            axis(app.UappPeaksAxes,"auto")


        end

        % Button pushed function: PlotPeaksButton_3
        function PlotPeaksButtonQPushed(app, event)
            [pk1,lk1] = findpeaks(app.q_yfilter,'MinPeakHeight',app.RMS_q.Value,'MinPeakDistance',100);
            [pk2,lk2] = findpeaks(-1*app.q_yfilter,'MinPeakHeight',app.RMS_q.Value,'MinPeakDistance',100);

            plot(app.QPeaksAxes,app.t_config,app.q_yfilter,Color='#ee5d6c',LineWidth = 1.5)
            hold(app.QPeaksAxes, 'on' )
            plot(app.QPeaksAxes,app.t_config(lk1),pk1,'o',Color='#6a0d83',LineWidth = 1.5)
            plot(app.QPeaksAxes,app.t_config(lk2),-pk2,'o',Color='#6a0d83',LineWidth = 1.5)
            axis(app.QPeaksAxes,"auto")
            ylim(app.QPeaksAxes,[-1.8*1e-7,1.5*1e-7]);

        end

        % Button down function: DetailsTab
        function DetailsTabButtonDown(app, event)
            % when user click on details tab, the startup value of p2p,
            % freq and power will automatically calculate from first cycle
            % until the dropdown value change
            app.Power_2.Value=power_cycle(app,1);
            [app.Peaktopeak_uapp_2.Value,app.Freq_uapp_2.Value]=find_p2pfq(app,app.t_config,app.uapp_yfilter,1);
            [app.Peaktopeak_q_2.Value,app.Freq_q_2.Value]=find_p2pfq(app,app.t_config,app.q_yfilter,1);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % when the dropdown value change, switch case was used to
            % assign value of order
            value = app.DropDown.Value;
            switch value
                case '1st Cycle'
                    order=1;
                case '2nd Cycle'
                    order=2;
                case '3rd Cycle'
                    order=3;
                otherwise
                    order=1;
            end
            % Find details function %

            % In this function only p2p and freq will update and order will
            % be input parameter tell what cycle data user would like to 
            % know 
            [app.Peaktopeak_uapp_2.Value,app.Freq_uapp_2.Value]=find_p2pfq(app,app.t_config,app.uapp_yfilter,order);

        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            switch value
                case '1st Cycle'
                    order=1;
                case '2nd Cycle'
                    order=2;
                case '3rd Cycle'
                    order=3;
                otherwise
                    order=1;
            end
            [app.Peaktopeak_q_2.Value,app.Freq_q_2.Value]=find_p2pfq(app,app.t_config,app.q_yfilter,order);
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value
                case '1st Cycle'
                    order=1;
                case '2nd Cycle'
                    order=2;
                case '3rd Cycle'
                    order=3;
                otherwise
                    order=1;
            end
            app.Power_2.Value=power_cycle(app,order);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Panel_3 = uipanel(app.MainTab);
            app.UappUIAxes = uiaxes(app.Panel_3);
            title(app.UappUIAxes, 'High Voltage Signal (U app)')
            xlabel(app.UappUIAxes, 'Time (s)')
            ylabel(app.UappUIAxes, 'Voltage (Volts)')
            zlabel(app.UappUIAxes, 'Z')
            app.SmoothSwitchLabel = uilabel(app.Panel_3);
            app.SmoothSwitchLabel.Text = 'Smooth';
            app.SmoothSwitch = uiswitch(app.Panel_3, 'toggle');
            app.SmoothSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothSwitchValueChanged, true);
            app.ImportButton_Uapp = uibutton(app.Panel_3, 'push');
            app.ImportButton_Uapp.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_UappPushed, true);
            app.ImportButton_Uapp.Icon = fullfile(pathToMLAPP, 'import.png');
            app.ImportButton_Uapp.Text = 'Import';
            app.FilenameEditFieldLabel = uilabel(app.Panel_3);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameUapp = uieditfield(app.Panel_3, 'text');
            app.Panel_4 = uipanel(app.MainTab);
            app.QUIAxes = uiaxes(app.Panel_4);
            title(app.QUIAxes, 'Charge in the Capacitor (Q)')
            xlabel(app.QUIAxes, 'Time (s)')
            ylabel(app.QUIAxes, 'Charge  (Coulombs)')
            zlabel(app.QUIAxes, 'Z')
            app.SmoothSwitch_2Label = uilabel(app.Panel_4);
            app.SmoothSwitch_2Label.Text = 'Smooth';
            app.SmoothSwitch_2 = uiswitch(app.Panel_4, 'toggle');
            app.SmoothSwitch_2.ValueChangedFcn = createCallbackFcn(app, @SmoothSwitch_2ValueChanged, true);
            app.FilenameEditFieldLabel_2 = uilabel(app.Panel_4);
            app.FilenameEditFieldLabel_2.Text = 'File name';
            app.FilenameUc = uieditfield(app.Panel_4, 'text');
            app.ImportButton_Uc = uibutton(app.Panel_4, 'push');
            app.ImportButton_Uc.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_UcPushed, true);
            app.ImportButton_Uc.Icon = fullfile(pathToMLAPP, 'import.png');
            app.ImportButton_Uc.Text = 'Import';
            app.Panel = uipanel(app.MainTab);
            app.PeaktopeakEditFieldLabel = uilabel(app.Panel);
            app.PeaktopeakEditFieldLabel.Text = 'Peak-to-peak';
            app.Peaktopeak_uapp = uieditfield(app.Panel, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.Panel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.Freq_uapp = uieditfield(app.Panel, 'numeric');
            app.RMSEditFieldLabel = uilabel(app.Panel);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMS_uapp = uieditfield(app.Panel, 'numeric');
            app.HzLabel = uilabel(app.Panel);
            app.HzLabel.Text = 'Hz';
            app.VLabel = uilabel(app.Panel);
            app.VLabel.Text = 'V';
            app.VLabel_3 = uilabel(app.Panel);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.Panel);
            app.VLabel_4.Text = 'V';
            app.MaxLabel = uilabel(app.Panel);
            app.MaxLabel.Text = 'Max';
            app.MaxLabel_2 = uilabel(app.Panel);
            app.MaxLabel_2.Text = 'Min';
            app.Min_Uapp = uieditfield(app.Panel, 'numeric');
            app.Max_Uapp = uieditfield(app.Panel, 'numeric');
            app.VLabel_2 = uilabel(app.Panel);
            app.VLabel_2.Text = 'V';
            app.Panel_2 = uipanel(app.MainTab);
            app.PeaktopeakEditField_2Label = uilabel(app.Panel_2);
            app.PeaktopeakEditField_2Label.Text = 'Peak-to-peak';
            app.Peaktopeak_q = uieditfield(app.Panel_2, 'numeric');
            app.RMS_q = uieditfield(app.Panel_2, 'numeric');
            app.RMSEditField_2Label = uilabel(app.Panel_2);
            app.RMSEditField_2Label.Text = 'RMS';
            app.FrequencyEditField_2Label = uilabel(app.Panel_2);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.Freq_q = uieditfield(app.Panel_2, 'numeric');
            app.CLabel = uilabel(app.Panel_2);
            app.CLabel.Text = 'C';
            app.CLabel_2 = uilabel(app.Panel_2);
            app.CLabel_2.Text = 'C';
            app.HzLabel_2 = uilabel(app.Panel_2);
            app.HzLabel_2.Text = 'Hz';
            app.MaxLabel_3 = uilabel(app.Panel_2);
            app.MaxLabel_3.Text = 'Max';
            app.Max_Q = uieditfield(app.Panel_2, 'numeric');
            app.MaxLabel_4 = uilabel(app.Panel_2);
            app.MaxLabel_4.Text = 'Min';
            app.Min_Q = uieditfield(app.Panel_2, 'numeric');
            app.VLabel_6 = uilabel(app.Panel_2);
            app.VLabel_6.Text = 'V';
            app.VLabel_5 = uilabel(app.Panel_2);
            app.VLabel_5.Text = 'V';
            app.Panel_5 = uipanel(app.MainTab);
            app.LissajousUIAxes = uiaxes(app.Panel_5);
            title(app.LissajousUIAxes, 'Lissajous Figure')
            xlabel(app.LissajousUIAxes, 'High Voltage Signal - U app (Volts)')
            ylabel(app.LissajousUIAxes, 'Charge (Coulombs)')
            zlabel(app.LissajousUIAxes, 'Z')
            app.LissajousPlotButton = uibutton(app.Panel_5, 'push');
            app.LissajousPlotButton.ButtonPushedFcn = createCallbackFcn(app, @LissajousPlotButtonPushed, true);
            app.LissajousPlotButton.Icon = fullfile(pathToMLAPP, 'scatter-plot.png');
            app.LissajousPlotButton.Text = 'Plot';
            app.SmoothLissajousFigureSwitchLabel = uilabel(app.Panel_5);
            app.SmoothLissajousFigureSwitchLabel.Text = 'Smooth Lissajous Figure';
            app.SmoothLissajousSwitch = uiswitch(app.Panel_5, 'slider');
            app.SmoothLissajousSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothLissajousSwitchValueChanged, true);
            app.Power = uieditfield(app.Panel_5, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.Panel_5);
            app.PowerEditFieldLabel.Text = 'Power';
            app.WattsLabel = uilabel(app.Panel_5);
            app.WattsLabel.Text = 'Watts';
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.DetailsTab.ButtonDownFcn = createCallbackFcn(app, @DetailsTabButtonDown, true);
            app.Panel_7 = uipanel(app.DetailsTab);
            app.QPeaksAxes = uiaxes(app.Panel_7);
            title(app.QPeaksAxes, 'Charge in the Capacitor (Q)')
            xlabel(app.QPeaksAxes, 'Time (s)')
            ylabel(app.QPeaksAxes, 'Charge (Coulombs)')
            zlabel(app.QPeaksAxes, 'Z')
            app.PlotPeaksButton_3 = uibutton(app.Panel_7, 'push');
            app.PlotPeaksButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotPeaksButtonQPushed, true);
            app.PlotPeaksButton_3.Icon = fullfile(pathToMLAPP, 'chart.png');
            app.PlotPeaksButton_3.Text = 'Plot Peaks';
            app.Peaktopeak_q_2 = uieditfield(app.Panel_7, 'numeric');
            app.Peaktopeak_q_label = uilabel(app.Panel_7);
            app.Peaktopeak_q_label.Text = 'Peak-to-peak';
            app.VLabel_8 = uilabel(app.Panel_7);
            app.VLabel_8.Text = 'V';
            app.FrequencyEditFieldLabel_3 = uilabel(app.Panel_7);
            app.FrequencyEditFieldLabel_3.Text = 'Frequency';
            app.Freq_q_2 = uieditfield(app.Panel_7, 'numeric');
            app.HzLabel_4 = uilabel(app.Panel_7);
            app.HzLabel_4.Text = 'Hz';
            app.DropDown_2 = uidropdown(app.Panel_7);
            app.DropDown_2.Items = {'1st Cycle', '2nd Cycle', '3rd Cycle'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '1st Cycle';
            app.Panel_8 = uipanel(app.DetailsTab);
            app.UappPeaksAxes = uiaxes(app.Panel_8);
            title(app.UappPeaksAxes, 'High Voltage Signal (U app)')
            xlabel(app.UappPeaksAxes, 'Time (s)')
            ylabel(app.UappPeaksAxes, 'Voltage (Volts)')
            zlabel(app.UappPeaksAxes, 'Z')
            app.PlotPeaksButton_2 = uibutton(app.Panel_8, 'push');
            app.PlotPeaksButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotPeaksButtonUPushed, true);
            app.PlotPeaksButton_2.Icon = fullfile(pathToMLAPP, 'chart.png');
            app.PlotPeaksButton_2.Text = 'Plot Peaks';
            app.Peaktopeak_uapp_label = uilabel(app.Panel_8);
            app.Peaktopeak_uapp_label.Text = 'Peak-to-peak';
            app.Peaktopeak_uapp_2 = uieditfield(app.Panel_8, 'numeric');
            app.VLabel_7 = uilabel(app.Panel_8);
            app.VLabel_7.Text = 'V';
            app.HzLabel_3 = uilabel(app.Panel_8);
            app.HzLabel_3.Text = 'Hz';
            app.FrequencyEditFieldLabel_2 = uilabel(app.Panel_8);
            app.FrequencyEditFieldLabel_2.Text = 'Frequency';
            app.Freq_uapp_2 = uieditfield(app.Panel_8, 'numeric');
            app.DropDown = uidropdown(app.Panel_8);
            app.DropDown.Items = {'1st Cycle', '2nd Cycle', '3rd Cycle'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1st Cycle';
            app.Panel_9 = uipanel(app.DetailsTab);
            app.UappQPeaksAxes = uiaxes(app.Panel_9);
            title(app.UappQPeaksAxes, 'Uapp - Q ')
            xlabel(app.UappQPeaksAxes, 'Time (s)')
            ylabel(app.UappQPeaksAxes, 'Voltage (V) ')
            zlabel(app.UappQPeaksAxes, 'Z')
            app.PlotPeaksButton = uibutton(app.Panel_9, 'push');
            app.PlotPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPeaksButtonPushed, true);
            app.PlotPeaksButton.Icon = fullfile(pathToMLAPP, 'scatter-graph.png');
            app.PlotPeaksButton.Text = 'Plot Peaks';
            app.WattsLabel_2 = uilabel(app.Panel_9);
            app.WattsLabel_2.Text = 'Watts';
            app.Power_2 = uieditfield(app.Panel_9, 'numeric');
            app.PowerEditFieldLabel_2 = uilabel(app.Panel_9);
            app.PowerEditFieldLabel_2.Text = 'Power';
            app.DropDown_3 = uidropdown(app.Panel_9);
            app.DropDown_3.Items = {'1st Cycle', '2nd Cycle', '3rd Cycle'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '1st Cycle';
            app.Panel_6 = uipanel(app.UIFigure);
            app.UniversityLogo = uiimage(app.Panel_6);
            app.UniversityLogo.ImageSource = fullfile(pathToMLAPP, 'univerisity_of_liverpool.png');
            app.Title = uilabel(app.Panel_6);
            app.Title.Text = 'ELECTRICAL SIGNAL ANALYSIS';
            app.Image = uiimage(app.Panel_6);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sinusoid_8562437.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UappQProgramming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end